classdef BackUpAPPnov < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Capitulo1Tab                 matlab.ui.container.Tab
        Capitulo2Tab                 matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        IterativosTab                matlab.ui.container.Tab
        ITERListBox                  matlab.ui.control.ListBox
        ITERsolucion                 matlab.ui.control.TextArea
        ITERmA31                     matlab.ui.control.EditField
        ITERmA21                     matlab.ui.control.EditField
        ITERmA33                     matlab.ui.control.EditField
        ITERmA23                     matlab.ui.control.EditField
        ITERmA13                     matlab.ui.control.EditField
        ITERmA11                     matlab.ui.control.EditField
        ITERmA32                     matlab.ui.control.EditField
        ITERmA22                     matlab.ui.control.EditField
        ITERmb3                      matlab.ui.control.EditField
        ITERmb2                      matlab.ui.control.EditField
        ITERmx03                     matlab.ui.control.EditField
        ITERmx02                     matlab.ui.control.EditField
        ITERmb1                      matlab.ui.control.EditField
        ITERmA12                     matlab.ui.control.EditField
        ITERmx01                     matlab.ui.control.EditField
        CalcularButton_2             matlab.ui.control.Button
        ITERradioe                   matlab.ui.control.EditField
        ITERTabla                    matlab.ui.control.Table
        ITERSolucinLabel             matlab.ui.control.Label
        RadioEspectralLabel_2        matlab.ui.control.Label
        IngreselatoleranciaLabel_2   matlab.ui.control.Label
        IngreseelnumerodeiteracionesLabel_2  matlab.ui.control.Label
        SeleccionaelmetodoLabel      matlab.ui.control.Label
        ITERtol                      matlab.ui.control.NumericEditField
        ITERnitter                   matlab.ui.control.NumericEditField
        IngreselamatrizbLabel_2      matlab.ui.control.Label
        IngreselamatrizALabel_2      matlab.ui.control.Label
        Ingreselamatrizx0Label_2     matlab.ui.control.Label
        SorTab                       matlab.ui.container.Tab
        SORsolucion                  matlab.ui.control.TextArea
        SORmA31                      matlab.ui.control.EditField
        SORmA21                      matlab.ui.control.EditField
        SORmA33                      matlab.ui.control.EditField
        SORmA23                      matlab.ui.control.EditField
        SORmA13                      matlab.ui.control.EditField
        SORmA11                      matlab.ui.control.EditField
        SORmA32                      matlab.ui.control.EditField
        SORmA22                      matlab.ui.control.EditField
        SORmb3                       matlab.ui.control.EditField
        SORmb2                       matlab.ui.control.EditField
        SORmx03                      matlab.ui.control.EditField
        SORmx02                      matlab.ui.control.EditField
        SORmb1                       matlab.ui.control.EditField
        SORmA12                      matlab.ui.control.EditField
        SORmx01                      matlab.ui.control.EditField
        CalcularButton               matlab.ui.control.Button
        SORradioe                    matlab.ui.control.EditField
        SORTabla                     matlab.ui.control.Table
        SolucinLabel                 matlab.ui.control.Label
        RadioEspectralLabel          matlab.ui.control.Label
        IngreselatoleranciaLabel     matlab.ui.control.Label
        IngreseelnumerodeiteracionesLabel  matlab.ui.control.Label
        IngreseelvalordewLabel       matlab.ui.control.Label
        SORtol                       matlab.ui.control.NumericEditField
        SORw                         matlab.ui.control.NumericEditField
        SORnitter                    matlab.ui.control.NumericEditField
        IngreselamatrizbLabel        matlab.ui.control.Label
        IngreselamatrizALabel        matlab.ui.control.Label
        Ingreselamatrizx0Label       matlab.ui.control.Label
        Capitulo3Tab                 matlab.ui.container.Tab
        TabGroup2_2                  matlab.ui.container.TabGroup
        VandermondeTab               matlab.ui.container.Tab
        VANDERy0                     matlab.ui.control.NumericEditField
        y0EditFieldLabel             matlab.ui.control.Label
        VANDERy1                     matlab.ui.control.NumericEditField
        y1EditFieldLabel             matlab.ui.control.Label
        VANDERy2                     matlab.ui.control.NumericEditField
        y2EditFieldLabel             matlab.ui.control.Label
        VANDERy3                     matlab.ui.control.NumericEditField
        y3EditFieldLabel             matlab.ui.control.Label
        VANDERx0                     matlab.ui.control.NumericEditField
        x0EditFieldLabel             matlab.ui.control.Label
        VANDERx1                     matlab.ui.control.NumericEditField
        x1EditFieldLabel             matlab.ui.control.Label
        VANDERx2                     matlab.ui.control.NumericEditField
        x2EditFieldLabel             matlab.ui.control.Label
        VANDERx3                     matlab.ui.control.NumericEditField
        x3EditFieldLabel             matlab.ui.control.Label
        VANDERListBox                matlab.ui.control.ListBox
        GraficarButton               matlab.ui.control.Button
        SeleccionaelgradoLabel_2     matlab.ui.control.Label
        IngreselasyLabel             matlab.ui.control.Label
        IngreselasxLabel             matlab.ui.control.Label
        VANDERAxes                   matlab.ui.control.UIAxes
        SplineTab                    matlab.ui.container.Tab
        SPLINEy0                     matlab.ui.control.NumericEditField
        y0EditFieldLabel_2           matlab.ui.control.Label
        SPLINEy1                     matlab.ui.control.NumericEditField
        y1EditFieldLabel_2           matlab.ui.control.Label
        SPLINEy2                     matlab.ui.control.NumericEditField
        y2EditFieldLabel_2           matlab.ui.control.Label
        SPLINEy3                     matlab.ui.control.NumericEditField
        y3EditFieldLabel_2           matlab.ui.control.Label
        SPLINEx0                     matlab.ui.control.NumericEditField
        x0EditFieldLabel_2           matlab.ui.control.Label
        SPLINEx1                     matlab.ui.control.NumericEditField
        x1EditFieldLabel_2           matlab.ui.control.Label
        SPLINEx2                     matlab.ui.control.NumericEditField
        x2EditFieldLabel_2           matlab.ui.control.Label
        SPLINEx3                     matlab.ui.control.NumericEditField
        x3EditFieldLabel_2           matlab.ui.control.Label
        SPLINEListBox                matlab.ui.control.ListBox
        GraficarSplineButton         matlab.ui.control.Button
        SeleccionaelgradoLabel       matlab.ui.control.Label
        IngreselasyLabel_2           matlab.ui.control.Label
        IngreselasxLabel_2           matlab.ui.control.Label
        NewtoninterpolanteTab        matlab.ui.container.Tab
        MatrizdediferenciasdivididasLabel  matlab.ui.control.Label
        NIy                          matlab.ui.control.EditField
        NIx                          matlab.ui.control.EditField
        NITabla                      matlab.ui.control.Table
        CalcularyGraficarButton      matlab.ui.control.Button
        IngreselosvaloresdeyLabel    matlab.ui.control.Label
        IngreselosvaloresdexLabel    matlab.ui.control.Label
        NIAxes                       matlab.ui.control.UIAxes
        LagrangeTab                  matlab.ui.container.Tab
        LAGy                         matlab.ui.control.EditField
        LAGx                         matlab.ui.control.EditField
        CalcularyGraficarButton_2    matlab.ui.control.Button
        IngreselosvaloresdeyLabel_2  matlab.ui.control.Label
        IngreselosvaloresdexLabel_2  matlab.ui.control.Label
        LAGAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CalcularButtonPushed(app, event)


        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed2(app, event)
            %Definir x0:
            x0 = [str2num(app.SORmx01.Value);str2num(app.SORmx02.Value);str2num(app.SORmx03.Value)];

            %Definir A:
            A = [str2num(app.SORmA11.Value) str2num(app.SORmA12.Value) str2num(app.SORmA13.Value);str2num(app.SORmA21.Value) str2num(app.SORmA22.Value) str2num(app.SORmA23.Value); str2num(app.SORmA31.Value) str2num(app.SORmA32.Value) str2num(app.SORmA33.Value)];
            
            %Definir B:
            b = [str2num(app.SORmb1.Value);str2num(app.SORmb2.Value);str2num(app.SORmb3.Value)];
            
            %Definir el resto de valores
            Tol = app.SORtol.Value;  %0.05;%app.SORtol.Value;
            niter = app.SORnitter.Value;    %100;%app.SORnitter.Value;
            w = app.SORw.Value;    %1;%app.SORw.Value;


            c = 0;
            n = 1;
            error = Tol + 1;

            D = diag(diag(A));
            L = -tril(A, -1);
            U = -triu(A, +1);

            % Inicializar arrays para almacenar los valores
            iteraciones = zeros(1, niter);
            x1_vals = zeros(niter, numel(x0));
            error_vals = zeros(1, niter);

            while error > Tol && c < niter
                T = inv(D - w * L) * ((1 - w) * D + w * U);
                C = w * inv(D - w * L) * b;
                x1 = T * x0 + C;

                % Calcular el error relativo
                E = norm((x1 - x0) ./ x1, 'inf');

                % Almacenar los valores en los arrays
                iteraciones(c + 1) = n;
                x1_vals(c + 1, :) = x1';
                error_vals(c + 1) = E;

                error = E;
                x0 = x1;
                c = c + 1;

                n = n + 1;
            end

            % Redimensionar los arrays para eliminar los elementos no utilizados
            iteraciones = iteraciones(1:c);
            x1_vals = x1_vals(1:c, :);
            error_vals = error_vals(1:c);
            
            % Crear la tabla utilizando array2table y establecer nombres de variables
            tabla = array2table([iteraciones', x1_vals, error_vals'], 'VariableNames', ...
                [{'n'}, strcat('x', arrayfun(@num2str, 1:numel(x0), 'UniformOutput', false)), 'Error']);


            if error < Tol
                s = x0;
                fprintf('Es una aproximación de la solución del sistema con una tolerancia = %f\n', Tol);
                app.SORTabla.Data = tabla;
                app.SORsolucion.Value = string(s);
                app.SORradioe.Value = num2str(max(abs(eig(A))));
            else
                s = x0;
                app.SORTabla.Data = tabla;
                result = {'Fracasó en ', num2str(niter), ' iteraciones'};
                app.SORsolucion.Value = strcat(result);
                app.SORradioe.Value = num2str(max(abs(eig(A))));
            end
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            s1 = app.ITERListBox.Value;
            s2 = 'Jacobi';
            valmet = strcmp(s1,s2);
            if valmet
                met = 0;
            else
                met = 1;
            end


            function x1 = NewJacobiSeid(x0,A,b,met)
                n=length(A);
                x1=x0;
                for i=1:n
                    sum=0;
                    for j=1:n
                        if j~=i && met==0
                            sum=sum+A(i,j)*x0(j);
                        elseif j~=i && met==1
                            sum=sum+A(i,j)*x1(j);
                        end
                    end
                    x1(i)=(b(i)-sum)/A(i,i);
                end
            end

            %Definir x0:
            x0 = [str2num(app.ITERmx01.Value);str2num(app.ITERmx02.Value);str2num(app.ITERmx03.Value)];

            %Definir A:
            A = [str2num(app.ITERmA11.Value) str2num(app.ITERmA12.Value) str2num(app.ITERmA13.Value);str2num(app.ITERmA21.Value) str2num(app.ITERmA22.Value) str2num(app.ITERmA23.Value); str2num(app.ITERmA31.Value) str2num(app.ITERmA32.Value) str2num(app.ITERmA33.Value)];
            
            %Definir B:
            b = [str2num(app.ITERmb1.Value);str2num(app.ITERmb2.Value);str2num(app.ITERmb3.Value)];
            
            %Definir el resto de valores
            Tol = app.ITERtol.Value;  %0.05;%app.SORtol.Value;
            niter = app.ITERnitter.Value;    %100;%app.SORnitter.Value;
            

            c=0;
            cou=1;
            error=Tol+1;
            while error>Tol && c<niter
                x1=NewJacobiSeid(x0,A,b,met);
                E(c+1)=norm(x1-x0,'inf');
                counter(c+1)=cou;
                error=E(c+1);
                x0=x1;
                c=c+1;
                cou=cou+1;
            end
            if error < Tol
                s=x0;
                n=c;
                s
                
                app.ITERTabla.Data = table(counter(:),E(:));
                app.ITERsolucion.Value = string(s);
                app.ITERradioe.Value = num2str(max(abs(eig(A))));
            else 
                s=x0;
                n=c;

                app.ITERTabla.Data = table(counter(:),E(:));
                result = {'Fracasó en ', num2str(niter), ' iteraciones'};
                app.ITERsolucion.Value = strcat(result);
                app.ITERradioe.Value = num2str(max(abs(eig(A))));
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)


            s1 = app.VANDERListBox.Value;
            s2 = 'Cuadrático';
            s3 = 'Cúbico';
            valmet = strcmp(s1,s2);
            valmet2 = strcmp(s1,s3);
            if valmet
                met = 2;
            elseif valmet2
                met = 3;
            else
                met = 1;
            end


            if met == 1
                x=[app.VANDERx1.Value app.VANDERx0.Value]'; %3 3.7 4.4
                y=[app.VANDERy1.Value app.VANDERy0.Value]'; %6 10 15
                
                A=[x ones(2,1)]%x.^3 x.^3
                b=y;
                a=inv(A)*b
                
                xpol=1:0.01:5;
                p=a(1)*xpol+a(2);%a(1)*xpol.^3+
                
                %plot(x,y,'r*',xpol,p,'b-')
                plot(app.VANDERAxes,x,y,'r*',xpol,p,'b-')

            elseif met == 2
                x=[app.VANDERx2.Value app.VANDERx1.Value app.VANDERx0.Value]'; %3 3.7 4.4
                y=[app.VANDERy2.Value app.VANDERy1.Value app.VANDERy0.Value]'; %6 10 15
                
                A=[x.^2 x ones(3,1)]%x.^3 x.^3
                b=y;
                a=inv(A)*b
                
                xpol=1:0.01:5;
                p=a(1)*xpol.^2+a(2)*xpol+a(3);%a(1)*xpol.^3+
                
                %plot(x,y,'r*',xpol,p,'b-')
                plot(app.VANDERAxes,x,y,'r*',xpol,p,'b-')
            
            elseif met == 3
                x=[app.VANDERx3.Value app.VANDERx2.Value app.VANDERx1.Value app.VANDERx0.Value]'; %3 3.7 4.4
                y=[app.VANDERy3.Value app.VANDERy2.Value app.VANDERy1.Value app.VANDERy0.Value]'; %6 10 15
                
                A=[x.^3 x.^2 x ones(4,1)]%x.^3 x.^3
                b=y;
                a=inv(A)*b
                
                xpol=1:0.01:5;
                p=a(1)*xpol.^3+a(2)*xpol.^2+a(3)*xpol+a(4);%a(1)*xpol.^3+
                
                plot(app.VANDERAxes,x,y,'r*',xpol,p,'b-')
            end

        end

        % Button pushed function: GraficarSplineButton
        function GraficarSplineButtonPushed(app, event)
            s1 = app.SPLINEListBox.Value;
            s2 = 'Cuadrático';
            valmet = strcmp(s1,s2);
            if valmet
                met = 0;
            else
                met = 1;
            end

            if met == 0
                x=[app.VANDERx2.Value app.VANDERx1.Value app.VANDERx0.Value]'; %3 3.7 4.4
                y=[app.VANDERy2.Value app.VANDERy1.Value app.VANDERy0.Value]'; %6 10 15
                
                A=[x.^2 x ones(3,1)]%x.^3 x.^3
                b=y;
                a=inv(A)*b
                
                xpol=1:0.01:5;
                p=a(1)*xpol.^2+a(2)*xpol+a(3);%a(1)*xpol.^3+
                
                %plot(x,y,'r*',xpol,p,'b-')
                plot(app.VANDERAxes,x,y,'r*',xpol,p,'b-')
            
            elseif met == 1
                x=[app.VANDERx3.Value app.VANDERx2.Value app.VANDERx1.Value app.VANDERx0.Value]'; %3 3.7 4.4
                y=[app.VANDERy3.Value app.VANDERy2.Value app.VANDERy1.Value app.VANDERy0.Value]'; %6 10 15
                
                A=[x.^3 x.^2 x ones(4,1)]%x.^3 x.^3
                b=y;
                a=inv(A)*b
                
                xpol=1:0.01:5;
                p=a(1)*xpol.^3+a(2)*xpol.^2+a(3)*xpol+a(4);%a(1)*xpol.^3+
                
                plot(app.VANDERAxes,x,y,'r*',xpol,p,'b-')
            end
        end

        % Button pushed function: CalcularyGraficarButton
        function CalcularyGraficarButtonPushed(app, event)
            xtemp = app.NIx.Value;
            ytemp = app.NIy.Value;
            disp(xtemp);
            disp(ytemp);
            x = str2double(strsplit(xtemp));
            y = str2double(strsplit(ytemp));
            disp(x);
            disp(y);
            
            function y = NewtonPolyEval(Tabla, x_eval)
                n = size(Tabla, 1);
                y = Tabla(1, 2);
            
                for i = 2:n
                    term = Tabla(i, i+1);
                    for j = 1:i-1
                        term = term * (x_eval - Tabla(j, 1));
                    end
                    y = y + term;
                end
            end

            
            n = length(x);
            Tabla = zeros(n, n+1);
            Tabla(:, 1) = x;
            Tabla(:, 2) = y;
        
            for j = 3:n+1
                for i = j-1:n
                    Tabla(i, j) = (Tabla(i, j-1) - Tabla(i-1, j-1)) / (Tabla(i, 1) - Tabla(i-j+2, 1));
                end
            end
        
            % Generar puntos para graficar el polinomio interpolante
            x_interp = linspace(min(x), max(x), 1000);
            y_interp = zeros(size(x_interp));
        
            % Evaluar el polinomio interpolante en los puntos interpolados
            for k = 1:length(x_interp)
                y_interp(k) = NewtonPolyEval(Tabla, x_interp(k));
            end
        
            % Graficar los puntos de datos y el polinomio interpolante
            hold(app.NIAxes, 'on')
            plot(app.NIAxes,x_interp, y_interp, 'r-', 'DisplayName', 'Polinomio Interpolante');
            title(app.NIAxes,'Interpolación de Newton');
            scatter(app.NIAxes,x, y, 'o', 'DisplayName', 'Datos');

            app.NITabla.Data = Tabla;

        end

        % Button pushed function: CalcularyGraficarButton_2
        function CalcularyGraficarButton_2Pushed(app, event)
            xtemp = app.LAGx.Value;
            ytemp = app.LAGy.Value;
            disp(xtemp);
            disp(ytemp);
            x = str2double(strsplit(xtemp));
            y = str2double(strsplit(ytemp));
            disp(x);
            disp(y);
        
            n = length(x);
            Tabla = zeros(n, n);
        
            % Itera sobre cada punto de datos
            for i = 1:n
                Li = 1;
                den = 1;
        
                % Itera sobre cada punto de datos nuevamente para construir el polinomio de Lagrange
                for j = 1:n
                    if j ~= i
                        paux = [1, -x(j)];
                        Li = conv(Li, paux);
                        den = den * (x(i) - x(j));
                    end
                end
        
                % Almacena los coeficientes correspondientes al i-ésimo punto en la tabla
                Tabla(i, :) = y(i) * Li / den;
            end
        
            % Suma los coeficientes para obtener el polinomio de interpolación
            pol = sum(Tabla);
        
            % Grafica los puntos de datos

        
            % Genera puntos para la curva del polinomio
            x_interp = linspace(min(x), max(x), 1000);
            y_interp = polyval(pol, x_interp);
        
            % Grafica la curva del polinomio de interpolación
            hold(app.LAGAxes, 'on');
            scatter(app.LAGAxes,x, y, 'o', 'MarkerEdgeColor', 'b', 'MarkerFaceColor', 'b');
            plot(app.LAGAxes,x_interp, y_interp, 'r', 'LineWidth', 2);
            
        
            % Configura el título y etiquetas del gráfico
            title(app.LAGAxes,'Polinomio de Interpolación de Lagrange');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Capitulo1Tab = uitab(app.TabGroup);
            app.Capitulo1Tab.Title = 'Capitulo 1';
            app.Capitulo2Tab = uitab(app.TabGroup);
            app.Capitulo2Tab.Title = 'Capitulo 2';
            app.TabGroup2 = uitabgroup(app.Capitulo2Tab);
            app.IterativosTab = uitab(app.TabGroup2);
            app.IterativosTab.Title = 'Iterativos';
            app.Ingreselamatrizx0Label_2 = uilabel(app.IterativosTab);
            app.Ingreselamatrizx0Label_2.Text = 'Ingrese la matriz x0:';
            app.IngreselamatrizALabel_2 = uilabel(app.IterativosTab);
            app.IngreselamatrizALabel_2.Text = 'Ingrese la matriz A:';
            app.IngreselamatrizbLabel_2 = uilabel(app.IterativosTab);
            app.IngreselamatrizbLabel_2.Text = 'Ingrese la matriz b:';
            app.ITERnitter = uieditfield(app.IterativosTab, 'numeric');
            app.ITERtol = uieditfield(app.IterativosTab, 'numeric');
            app.SeleccionaelmetodoLabel = uilabel(app.IterativosTab);
            app.SeleccionaelmetodoLabel.Text = 'Selecciona el metodo:';
            app.IngreseelnumerodeiteracionesLabel_2 = uilabel(app.IterativosTab);
            app.IngreseelnumerodeiteracionesLabel_2.Text = 'Ingrese el numero de iteraciones:';
            app.IngreselatoleranciaLabel_2 = uilabel(app.IterativosTab);
            app.IngreselatoleranciaLabel_2.Text = 'Ingrese la tolerancia:';
            app.RadioEspectralLabel_2 = uilabel(app.IterativosTab);
            app.RadioEspectralLabel_2.Text = 'Radio Espectral';
            app.ITERSolucinLabel = uilabel(app.IterativosTab);
            app.ITERSolucinLabel.Text = 'Solución';
            app.ITERTabla = uitable(app.IterativosTab);
            app.ITERTabla.ColumnName = {'n'; 'Error'};
            app.ITERTabla.RowName = {};
            app.ITERradioe = uieditfield(app.IterativosTab, 'text');
            app.ITERradioe.Editable = 'off';
            app.CalcularButton_2 = uibutton(app.IterativosTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.ITERmx01 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA12 = uieditfield(app.IterativosTab, 'text');
            app.ITERmb1 = uieditfield(app.IterativosTab, 'text');
            app.ITERmx02 = uieditfield(app.IterativosTab, 'text');
            app.ITERmx03 = uieditfield(app.IterativosTab, 'text');
            app.ITERmb2 = uieditfield(app.IterativosTab, 'text');
            app.ITERmb3 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA22 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA32 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA11 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA13 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA23 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA33 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA21 = uieditfield(app.IterativosTab, 'text');
            app.ITERmA31 = uieditfield(app.IterativosTab, 'text');
            app.ITERsolucion = uitextarea(app.IterativosTab);
            app.ITERsolucion.Editable = 'off';
            app.ITERListBox = uilistbox(app.IterativosTab);
            app.ITERListBox.Items = {'Jacobi', 'Gauss Seidel'};
            app.ITERListBox.Value = 'Jacobi';
            app.SorTab = uitab(app.TabGroup2);
            app.SorTab.Title = 'Sor';
            app.Ingreselamatrizx0Label = uilabel(app.SorTab);
            app.Ingreselamatrizx0Label.Text = 'Ingrese la matriz x0:';
            app.IngreselamatrizALabel = uilabel(app.SorTab);
            app.IngreselamatrizALabel.Text = 'Ingrese la matriz A:';
            app.IngreselamatrizbLabel = uilabel(app.SorTab);
            app.IngreselamatrizbLabel.Text = 'Ingrese la matriz b:';
            app.SORnitter = uieditfield(app.SorTab, 'numeric');
            app.SORw = uieditfield(app.SorTab, 'numeric');
            app.SORtol = uieditfield(app.SorTab, 'numeric');
            app.IngreseelvalordewLabel = uilabel(app.SorTab);
            app.IngreseelvalordewLabel.Text = 'Ingrese el valor de w:';
            app.IngreseelnumerodeiteracionesLabel = uilabel(app.SorTab);
            app.IngreseelnumerodeiteracionesLabel.Text = 'Ingrese el numero de iteraciones:';
            app.IngreselatoleranciaLabel = uilabel(app.SorTab);
            app.IngreselatoleranciaLabel.Text = 'Ingrese la tolerancia:';
            app.RadioEspectralLabel = uilabel(app.SorTab);
            app.RadioEspectralLabel.Text = 'Radio Espectral';
            app.SolucinLabel = uilabel(app.SorTab);
            app.SolucinLabel.Text = 'Solución';
            app.SORTabla = uitable(app.SorTab);
            app.SORTabla.ColumnName = {'n'; 'x1'; 'x2'; 'x3'; 'Error'};
            app.SORTabla.RowName = {};
            app.SORradioe = uieditfield(app.SorTab, 'text');
            app.SORradioe.Editable = 'off';
            app.CalcularButton = uibutton(app.SorTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed2, true);
            app.CalcularButton.Text = 'Calcular';
            app.SORmx01 = uieditfield(app.SorTab, 'text');
            app.SORmA12 = uieditfield(app.SorTab, 'text');
            app.SORmb1 = uieditfield(app.SorTab, 'text');
            app.SORmx02 = uieditfield(app.SorTab, 'text');
            app.SORmx03 = uieditfield(app.SorTab, 'text');
            app.SORmb2 = uieditfield(app.SorTab, 'text');
            app.SORmb3 = uieditfield(app.SorTab, 'text');
            app.SORmA22 = uieditfield(app.SorTab, 'text');
            app.SORmA32 = uieditfield(app.SorTab, 'text');
            app.SORmA11 = uieditfield(app.SorTab, 'text');
            app.SORmA13 = uieditfield(app.SorTab, 'text');
            app.SORmA23 = uieditfield(app.SorTab, 'text');
            app.SORmA33 = uieditfield(app.SorTab, 'text');
            app.SORmA21 = uieditfield(app.SorTab, 'text');
            app.SORmA31 = uieditfield(app.SorTab, 'text');
            app.SORsolucion = uitextarea(app.SorTab);
            app.SORsolucion.Editable = 'off';
            app.Capitulo3Tab = uitab(app.TabGroup);
            app.Capitulo3Tab.Title = 'Capitulo 3';
            app.TabGroup2_2 = uitabgroup(app.Capitulo3Tab);
            app.VandermondeTab = uitab(app.TabGroup2_2);
            app.VandermondeTab.Title = 'Vandermonde';
            app.VANDERAxes = uiaxes(app.VandermondeTab);
            xlabel(app.VANDERAxes, 'X')
            ylabel(app.VANDERAxes, 'Y')
            zlabel(app.VANDERAxes, 'Z')
            app.IngreselasxLabel = uilabel(app.VandermondeTab);
            app.IngreselasxLabel.Text = 'Ingrese las x:';
            app.IngreselasyLabel = uilabel(app.VandermondeTab);
            app.IngreselasyLabel.Text = 'Ingrese las y:';
            app.SeleccionaelgradoLabel_2 = uilabel(app.VandermondeTab);
            app.SeleccionaelgradoLabel_2.Text = 'Selecciona el grado:';
            app.GraficarButton = uibutton(app.VandermondeTab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.VANDERListBox = uilistbox(app.VandermondeTab);
            app.VANDERListBox.Items = {'Lineal', 'Cuadrático', 'Cúbico'};
            app.VANDERListBox.Value = 'Cuadrático';
            app.x3EditFieldLabel = uilabel(app.VandermondeTab);
            app.x3EditFieldLabel.Text = 'x^3';
            app.VANDERx3 = uieditfield(app.VandermondeTab, 'numeric');
            app.x2EditFieldLabel = uilabel(app.VandermondeTab);
            app.x2EditFieldLabel.Text = 'x^2';
            app.VANDERx2 = uieditfield(app.VandermondeTab, 'numeric');
            app.x1EditFieldLabel = uilabel(app.VandermondeTab);
            app.x1EditFieldLabel.Text = 'x^1';
            app.VANDERx1 = uieditfield(app.VandermondeTab, 'numeric');
            app.x0EditFieldLabel = uilabel(app.VandermondeTab);
            app.x0EditFieldLabel.Text = 'x^0';
            app.VANDERx0 = uieditfield(app.VandermondeTab, 'numeric');
            app.y3EditFieldLabel = uilabel(app.VandermondeTab);
            app.y3EditFieldLabel.Text = 'y^3';
            app.VANDERy3 = uieditfield(app.VandermondeTab, 'numeric');
            app.y2EditFieldLabel = uilabel(app.VandermondeTab);
            app.y2EditFieldLabel.Text = 'y^2';
            app.VANDERy2 = uieditfield(app.VandermondeTab, 'numeric');
            app.y1EditFieldLabel = uilabel(app.VandermondeTab);
            app.y1EditFieldLabel.Text = 'y^1';
            app.VANDERy1 = uieditfield(app.VandermondeTab, 'numeric');
            app.y0EditFieldLabel = uilabel(app.VandermondeTab);
            app.y0EditFieldLabel.Text = 'y^0';
            app.VANDERy0 = uieditfield(app.VandermondeTab, 'numeric');
            app.SplineTab = uitab(app.TabGroup2_2);
            app.SplineTab.Title = 'Spline';
            app.IngreselasxLabel_2 = uilabel(app.SplineTab);
            app.IngreselasxLabel_2.Text = 'Ingrese las x:';
            app.IngreselasyLabel_2 = uilabel(app.SplineTab);
            app.IngreselasyLabel_2.Text = 'Ingrese las y:';
            app.SeleccionaelgradoLabel = uilabel(app.SplineTab);
            app.SeleccionaelgradoLabel.Text = 'Selecciona el grado:';
            app.GraficarSplineButton = uibutton(app.SplineTab, 'push');
            app.GraficarSplineButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarSplineButtonPushed, true);
            app.GraficarSplineButton.Text = 'Graficar';
            app.SPLINEListBox = uilistbox(app.SplineTab);
            app.SPLINEListBox.Items = {'Lineal', 'Cuadrático', 'Cúbico'};
            app.SPLINEListBox.Value = 'Lineal';
            app.x3EditFieldLabel_2 = uilabel(app.SplineTab);
            app.x3EditFieldLabel_2.Text = 'x^3';
            app.SPLINEx3 = uieditfield(app.SplineTab, 'numeric');
            app.x2EditFieldLabel_2 = uilabel(app.SplineTab);
            app.x2EditFieldLabel_2.Text = 'x^2';
            app.SPLINEx2 = uieditfield(app.SplineTab, 'numeric');
            app.x1EditFieldLabel_2 = uilabel(app.SplineTab);
            app.x1EditFieldLabel_2.Text = 'x^1';
            app.SPLINEx1 = uieditfield(app.SplineTab, 'numeric');
            app.x0EditFieldLabel_2 = uilabel(app.SplineTab);
            app.x0EditFieldLabel_2.Text = 'x^0';
            app.SPLINEx0 = uieditfield(app.SplineTab, 'numeric');
            app.y3EditFieldLabel_2 = uilabel(app.SplineTab);
            app.y3EditFieldLabel_2.Text = 'y^3';
            app.SPLINEy3 = uieditfield(app.SplineTab, 'numeric');
            app.y2EditFieldLabel_2 = uilabel(app.SplineTab);
            app.y2EditFieldLabel_2.Text = 'y^2';
            app.SPLINEy2 = uieditfield(app.SplineTab, 'numeric');
            app.y1EditFieldLabel_2 = uilabel(app.SplineTab);
            app.y1EditFieldLabel_2.Text = 'y^1';
            app.SPLINEy1 = uieditfield(app.SplineTab, 'numeric');
            app.y0EditFieldLabel_2 = uilabel(app.SplineTab);
            app.y0EditFieldLabel_2.Text = 'y^0';
            app.SPLINEy0 = uieditfield(app.SplineTab, 'numeric');
            app.NewtoninterpolanteTab = uitab(app.TabGroup2_2);
            app.NewtoninterpolanteTab.Title = 'Newton interpolante';
            app.NIAxes = uiaxes(app.NewtoninterpolanteTab);
            xlabel(app.NIAxes, 'X')
            ylabel(app.NIAxes, 'Y')
            zlabel(app.NIAxes, 'Z')
            app.IngreselosvaloresdexLabel = uilabel(app.NewtoninterpolanteTab);
            app.IngreselosvaloresdexLabel.Text = 'Ingrese los valores de x:';
            app.IngreselosvaloresdeyLabel = uilabel(app.NewtoninterpolanteTab);
            app.IngreselosvaloresdeyLabel.Text = 'Ingrese los valores de y:';
            app.CalcularyGraficarButton = uibutton(app.NewtoninterpolanteTab, 'push');
            app.CalcularyGraficarButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularyGraficarButtonPushed, true);
            app.CalcularyGraficarButton.Text = 'Calcular y Graficar';
            app.NITabla = uitable(app.NewtoninterpolanteTab);
            app.NITabla.ColumnName = '';
            app.NITabla.RowName = {};
            app.NIx = uieditfield(app.NewtoninterpolanteTab, 'text');
            app.NIy = uieditfield(app.NewtoninterpolanteTab, 'text');
            app.MatrizdediferenciasdivididasLabel = uilabel(app.NewtoninterpolanteTab);
            app.MatrizdediferenciasdivididasLabel.Text = 'Matriz de diferencias divididas';
            app.LagrangeTab = uitab(app.TabGroup2_2);
            app.LagrangeTab.Title = 'Lagrange';
            app.LAGAxes = uiaxes(app.LagrangeTab);
            xlabel(app.LAGAxes, 'X')
            ylabel(app.LAGAxes, 'Y')
            zlabel(app.LAGAxes, 'Z')
            app.IngreselosvaloresdexLabel_2 = uilabel(app.LagrangeTab);
            app.IngreselosvaloresdexLabel_2.Text = 'Ingrese los valores de x:';
            app.IngreselosvaloresdeyLabel_2 = uilabel(app.LagrangeTab);
            app.IngreselosvaloresdeyLabel_2.Text = 'Ingrese los valores de y:';
            app.CalcularyGraficarButton_2 = uibutton(app.LagrangeTab, 'push');
            app.CalcularyGraficarButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularyGraficarButton_2Pushed, true);
            app.CalcularyGraficarButton_2.Text = 'Calcular y Graficar';
            app.LAGx = uieditfield(app.LagrangeTab, 'text');
            app.LAGy = uieditfield(app.LagrangeTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BackUpAPPnov

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end